classdef Plot_Testing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        Button       matlab.ui.control.Button
    end

    
    properties (Access = private)
        LogDatetime; % Description
        Log_Timer;
        Timer_Interval = 1;
        YValue;
        Test_Plot;
    end
    
    methods (Access = private)
        
        function SubPlot(app)
%             x = linspace(0,50,1000);
%             y = 30*sin(x);
%             app.UIAxes.XLim = [0,max(x)];
%             app.UIAxes.YLim = [min(y),max(y)];
%             h = animatedline(app.UIAxes);
%             for k = 1:length(x)
%                 addpoints(h,x(k),y(k));
%                  drawnow limitrate
%             end
            %%plot(app.UIAxes,x,y)
            %app.UIAxes.XLim = [0,length(app.LogDatetime)];
            app.UIAxes.YLim = [0,max(app.YValue)];
          plot(app.UIAxes,app.LogDatetime, app.YValue);
           
        end
        
        function Update_Timer(app)
            app.LogDatetime = [app.LogDatetime,datetime];
            app.YValue = [app.YValue, app.Slider.Value];
            app.Plot_Refresh();
            
        end
        
        function Plot_Refresh(app)
            
         app.Test_Plot.XData = app.LogDatetime;
         app.Test_Plot.YData = app.YValue;
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Log_Timer = timer;
            app.Log_Timer.TimerFcn = @(~,~) app.Update_Timer;
            app.Log_Timer.Period = app.Timer_Interval   ;
            app.Log_Timer.ExecutionMode = 'fixedRate';
            start(app.Log_Timer);
            app.Test_Plot =plot(app.UIAxes,app.LogDatetime, app.YValue);
%            app.Test_Plot.XDataSource = 'app.LogDatetime';
%            app.Test_Plot.YDataSource = 'app.YValue';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.SubPlot();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(timerfindall);
            delete(timerfindall);
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_Testing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end