classdef Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        XAxisDropDownLabel      matlab.ui.control.Label
        XAxisDropDown           matlab.ui.control.DropDown
        YAxisDropDownLabel      matlab.ui.control.Label
        YAxisDropDown           matlab.ui.control.DropDown
        YAxisDropDown_2Label    matlab.ui.control.Label
        YAxisDropDown_2         matlab.ui.control.DropDown
        XAxisDropDown_2Label    matlab.ui.control.Label
        XAxisDropDown_2         matlab.ui.control.DropDown
        LogCheckBox             matlab.ui.control.CheckBox
        Update_Plot             matlab.ui.control.Button
        MovingAvgCheckBox       matlab.ui.control.CheckBox
        SampleEditFieldLabel    matlab.ui.control.Label
        SampleEditField         matlab.ui.control.NumericEditField
        LogCheckBox_2           matlab.ui.control.CheckBox
        MovingAvgCheckBox_2     matlab.ui.control.CheckBox
        SampleEditField_2Label  matlab.ui.control.Label
        SampleEditField_2       matlab.ui.control.NumericEditField
        GridCheckBox            matlab.ui.control.CheckBox
        GridCheckBox_2          matlab.ui.control.CheckBox
        Button                  matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        UIAxesFull              matlab.ui.control.UIAxes
        Tab2                    matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        Tab3                    matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    
        Data_MGMT;
        Instruments;

        Main_Plot;
        Top_SubPlot;
        Bottom_Subplot;
        Update_Functions;
    end
    
    methods (Access = private)


        function Populate_Data(app)
          
            app.Add_X_Data();
            app.Add_Y_Data();
            app.Read_Data_to_Table();
            app.Main_Plot = Plot_Management();
            
            app.Top_SubPlot = Plot_Management();
            app.Bottom_Subplot = Plot_Management();
            
        end
    
        function Add_X_Data(app)
            app.XAxisDropDown.Items = {'Full Time','Custom Range'};
            app.XAxisDropDown_2.Items = {'Full Time','Custom Range'};
        end
    
        function Add_Y_Data(app)
            
           for idx = 1:length(app.Data_MGMT.Instruments)
               app.YAxisDropDown.Items(idx) ={ app.Data_MGMT.Name_Sensor(idx)};  
               app.YAxisDropDown_2.Items(idx) ={ app.Data_MGMT.Name_Sensor(idx)};  
           end
            
        end
        
        
        function  Read_Data_to_Table(app)
            app.UITable.Data = app.Data_MGMT.Data;
            for idx =1:length(app.Data_MGMT.Data.Properties.VariableNames)
                app.UITable.ColumnName(idx) =  app.Data_MGMT.Data.Properties.VariableNames(idx); 
            end
            
            
        end
        
        function Update_Plots(app)
            app.Main_Plot.X_Data = app.Data_MGMT.Data.DateTime;       
            
            app.Main_Plot.Plot = plot(app.UIAxesFull, app.Main_Plot.X_Data,app.Main_Plot.Y_Data);
            app.Top_SubPlot.X_Data =app.Data_MGMT.Data.DateTime;  
            app.Top_SubPlot.Plot = plot(app.UIAxes, app.Top_SubPlot.X_Data,app.Top_SubPlot.Y_Data);
        end
    
    function Update_SubPlot(app)
            app.Bottom_Subplot.X_Data =app.Data_MGMT.Data.DateTime;  
            app.Bottom_Subplot.Plot = plot(app.UIAxes_2, app.Bottom_Subplot.X_Data,app.Bottom_Subplot.Y_Data);
        end


        function box1(app)
            msgbox('one');
            pause(2)
        end
        
        function box2(app)
            msgbox('two');
            pause(2)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 0;
            app.Data_MGMT = Data_Management();
            app.Data_MGMT.Open_File();
   
            if app.Data_MGMT.File_Status == 1
                           disp('File open?')
                  disp(app.Data_MGMT.File_Status)
                app.Populate_Data();
                
            end
           
            
             app.Data_MGMT.Add_Update_Function( @app.box1);
             app.UIFigure.Visible = 1;
        end

        % Button pushed function: Update_Plot
        function Update_PlotButtonPushed(app, event)
      
            
            for idx =1:length(app.Data_MGMT.Update_Functions)
              app.Data_MGMT.Update_Functions{idx}()
              
          
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Tab'
                    app.XAxisDropDownLabel.Visible = 'on';
                    app.YAxisDropDownLabel.Visible = 'on';
                    app.XAxisDropDown_2Label.Visible = 'off';
                    app.YAxisDropDown_2Label.Visible = 'off';
                    app.XAxisDropDown.Visible = 'on';
                    app.YAxisDropDown.Visible = 'on';
                    app.XAxisDropDown_2.Visible = 'off';
                    app.YAxisDropDown_2.Visible = 'off';
                    app.LogCheckBox_2.Visible = 'off';
                    app.MovingAvgCheckBox_2.Visible = 'off';
                    app.SampleEditField_2.Visible = 'off';
                    
                case'Tab2'
                     app.XAxisDropDownLabel.Visible = 'on';
                    app.YAxisDropDownLabel.Visible = 'on';
                    app.XAxisDropDown.Visible = 'on';
                    app.YAxisDropDown.Visible = 'on';
                    app.XAxisDropDown_2Label.Visible = 'on';
                    app.YAxisDropDown_2Label.Visible = 'on';
                    app.XAxisDropDown_2.Visible = 'on';
                    app.YAxisDropDown_2.Visible = 'on';
                    app.LogCheckBox_2.Visible = 'on';
                    app.MovingAvgCheckBox_2.Visible = 'on';
                    app.SampleEditField_2.Visible = 'on';
                case 'Tab3'
                    app.XAxisDropDownLabel.Visible = 'off';
                    app.YAxisDropDownLabel.Visible = 'off';
                    app.XAxisDropDown_2Label.Visible = 'off';
                    app.YAxisDropDown_2Label.Visible = 'off';
                    app.XAxisDropDown.Visible = 'off';
                    app.YAxisDropDown.Visible = 'off';
                    app.XAxisDropDown_2.Visible = 'off';
                    app.YAxisDropDown_2.Visible = 'off';
                    app.LogCheckBox.Visible = 'off';
                    app.MovingAvgCheckBox.Visible = 'off';
                    app.SampleEditField.Visible = 'off';
                    app.LogCheckBox_2.Visible = 'off';
                    app.MovingAvgCheckBox_2.Visible = 'off';
                    app.SampleEditField_2.Visible = 'off';
                    
            end
            
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
           app.Main_Plot.Y_Data =  app.Data_MGMT.Search_Label(app.YAxisDropDown.Value);
           title(app.UIAxesFull, app.YAxisDropDown.Value);
           xlabel(app.UIAxesFull,[app.YAxisDropDown.Value ,' (K)']);
           app.Top_SubPlot.Y_Data =  app.Data_MGMT.Search_Label(app.YAxisDropDown.Value);
           title(app.UIAxes, app.YAxisDropDown.Value);
           xlabel(app.UIAxes,[app.YAxisDropDown.Value ,' (K)']);
           app.Update_Plots();
        end

        % Value changed function: XAxisDropDown_2
        function XAxisDropDown_2ValueChanged(app, event)
  
         
        end

        % Value changed function: YAxisDropDown_2
        function YAxisDropDown_2ValueChanged(app, event)
             app.Bottom_Subplot.Y_Data =  app.Data_MGMT.Search_Label(app.YAxisDropDown_2.Value);        
            title(app.UIAxes_2, app.YAxisDropDown_2.Value);        
            xlabel(app.UIAxes,[app.YAxisDropDown_2.Value ,' (K)']);         
            app.Update_SubPlot();
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            if (app.LogCheckBox.Value) == 1
                app.UIAxesFull.YScale = 'log';
                app.UIAxes.YScale = 'log';
                %app.Main_Plot.Plot = semilogy(app.UIAxesFull, app.Main_Plot.X_Data,app.Main_Plot.Y_Data);
            else 
                app.UIAxesFull.YScale = 'linear';
                app.UIAxes.YScale = 'linear';
            end
           
        end

        % Value changed function: LogCheckBox_2
        function LogCheckBox_2ValueChanged(app, event)
            if (app.LogCheckBox_2.Value) == 1
                app.UIAxes_2.YScale = 'log';
            else 
                app.UIAxes_2.YScale = 'linear';
            end
        end

        % Value changed function: MovingAvgCheckBox
        function MovingAvgCheckBoxValueChanged(app, event)
            if app.MovingAvgCheckBox.Value ==1
                app.Main_Plot.Plot.YData = movmean(app.Main_Plot.Y_Data,app.SampleEditField.Value);
                app.Top_SubPlot.Plot.YData = movmean(app.Top_SubPlot.Y_Data,app.SampleEditField.Value);
            else
                app.Main_Plot.Plot.YData  = app.Main_Plot.Y_Data;
                app.Top_SubPlot.Plot.YData  = app.Top_SubPlot.Y_Data;
            end
        end

        % Value changed function: MovingAvgCheckBox_2
        function MovingAvgCheckBox_2ValueChanged(app, event)
             if app.MovingAvgCheckBox_2.Value ==1
                app.Bottom_Subplot.Plot.YData = movmean(app.Bottom_Subplot.Y_Data,app.SampleEditField_2.Value);
            else
                app.Bottom_Subplot.Plot.YData  = app.Bottom_Subplot.Y_Data;
             end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Data_MGMT.Add_Update_Function(@app.box2);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.XAxisDropDownLabel = uilabel(app.LeftPanel);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.LeftPanel);
            app.XAxisDropDown.Items = {};
            app.XAxisDropDown.Value = {};
            app.YAxisDropDownLabel = uilabel(app.LeftPanel);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.LeftPanel);
            app.YAxisDropDown.Items = {};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = {};
            app.YAxisDropDown_2Label = uilabel(app.LeftPanel);
            app.YAxisDropDown_2Label.Visible = 'off';
            app.YAxisDropDown_2Label.Text = 'Y Axis';
            app.YAxisDropDown_2 = uidropdown(app.LeftPanel);
            app.YAxisDropDown_2.Items = {};
            app.YAxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDown_2ValueChanged, true);
            app.YAxisDropDown_2.Visible = 'off';
            app.YAxisDropDown_2.Value = {};
            app.XAxisDropDown_2Label = uilabel(app.LeftPanel);
            app.XAxisDropDown_2Label.Visible = 'off';
            app.XAxisDropDown_2Label.Text = 'X Axis';
            app.XAxisDropDown_2 = uidropdown(app.LeftPanel);
            app.XAxisDropDown_2.Items = {};
            app.XAxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDown_2ValueChanged, true);
            app.XAxisDropDown_2.Visible = 'off';
            app.XAxisDropDown_2.Value = {};
            app.LogCheckBox = uicheckbox(app.LeftPanel);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.Update_Plot = uibutton(app.LeftPanel, 'push');
            app.Update_Plot.ButtonPushedFcn = createCallbackFcn(app, @Update_PlotButtonPushed, true);
            app.Update_Plot.Text = 'Update';
            app.MovingAvgCheckBox = uicheckbox(app.LeftPanel);
            app.MovingAvgCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovingAvgCheckBoxValueChanged, true);
            app.MovingAvgCheckBox.Text = 'Moving Avg';
            app.SampleEditFieldLabel = uilabel(app.LeftPanel);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SampleEditField.Limits = [2 360];
            app.SampleEditField.RoundFractionalValues = 'on';
            app.SampleEditField.Value = 10;
            app.LogCheckBox_2 = uicheckbox(app.LeftPanel);
            app.LogCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LogCheckBox_2ValueChanged, true);
            app.LogCheckBox_2.Visible = 'off';
            app.LogCheckBox_2.Text = 'Log';
            app.MovingAvgCheckBox_2 = uicheckbox(app.LeftPanel);
            app.MovingAvgCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MovingAvgCheckBox_2ValueChanged, true);
            app.MovingAvgCheckBox_2.Visible = 'off';
            app.MovingAvgCheckBox_2.Text = 'Moving Avg';
            app.SampleEditField_2Label = uilabel(app.LeftPanel);
            app.SampleEditField_2Label.Visible = 'off';
            app.SampleEditField_2Label.Text = 'Sample';
            app.SampleEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.SampleEditField_2.Limits = [2 360];
            app.SampleEditField_2.Visible = 'off';
            app.SampleEditField_2.Value = 10;
            app.GridCheckBox = uicheckbox(app.LeftPanel);
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox_2 = uicheckbox(app.LeftPanel);
            app.GridCheckBox_2.Visible = 'off';
            app.GridCheckBox_2.Text = 'Grid';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxesFull = uiaxes(app.Tab);
            title(app.UIAxesFull, 'Title')
            xlabel(app.UIAxesFull, 'X')
            ylabel(app.UIAxesFull, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.Tab2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UITable = uitable(app.Tab3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end