classdef Lab_Command < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LC_Main_Window                  matlab.ui.Figure
        Project_Menu_Bar                matlab.ui.container.Menu
        New_Project_Menu_Bar            matlab.ui.container.Menu
        Open_Project_Menu_Bar           matlab.ui.container.Menu
        Save_Project_Menu_Bar           matlab.ui.container.Menu
        Configure_Project_Menu_Bar      matlab.ui.container.Menu
        Exit_Project_Menu_Bar           matlab.ui.container.Menu
        Plot_Menu_Bar                   matlab.ui.container.Menu
        ConfigurationMenu               matlab.ui.container.Menu
        Main_Large_Axes                 matlab.ui.control.UIAxes
        Main_Sub_Axes_Top               matlab.ui.control.UIAxes
        Main_Sub_Axes_Bottom            matlab.ui.control.UIAxes
        Control_Tabs                    matlab.ui.container.TabGroup
        Lakeshore_Control_Tab           matlab.ui.container.Tab
        Lakeshore_GUI_Link_Heaters_CheckBox  matlab.ui.control.CheckBox
        Lakeshore_GUI_Set_Temperature_Button  matlab.ui.control.Button
        Lakeshore_GUI_Setpoint_Label    matlab.ui.control.Label
        Lakeshore_GUI_Setpoint_A_Units_Text  matlab.ui.control.Label
        Lakeshore_GUI_Heater_Range_A_DropDown  matlab.ui.control.DropDown
        Lakeshore_GUI_Heater_Range_Label  matlab.ui.control.Label
        Lakeshore_GUI_Setpoint_C_Units_Text  matlab.ui.control.Label
        Lakeshore_GUI_Heater_Range_C_DropDown  matlab.ui.control.DropDown
        Lakeshore_300K_Button           matlab.ui.control.Button
        Lakeshore_10K_Button            matlab.ui.control.Button
        Lakeshore_150K_Button           matlab.ui.control.Button
        Lakeshore_GUI_A_Label           matlab.ui.control.Label
        Lakeshore_GUI_C_Label           matlab.ui.control.Label
        Lakeshore_GUI_Quick_Set_Label   matlab.ui.control.Label
        Lakeshore_Setpoint_A            matlab.ui.control.NumericEditField
        Lakeshore_Setpoint_C            matlab.ui.control.NumericEditField
        Sierra_Control_Tab              matlab.ui.container.Tab
        Sierra_GUI_Flowrate_Text        matlab.ui.control.Label
        Sierra_GUI_Flowrate_Label       matlab.ui.control.Label
        Sierra_GUI_Flowrate_Units_Text  matlab.ui.control.Label
        Sierra_GUI_Mol_Transferred_Label  matlab.ui.control.Label
        Sierra_GUI_Mol_Transferred_Text  matlab.ui.control.Label
        Sierra_GUI_Units_Transfered_Text  matlab.ui.control.Label
        Sierra_GUI_Mol_Remianing_Label  matlab.ui.control.Label
        Sierra_GUI_Mol_Remaining_Text   matlab.ui.control.Label
        Sierra_GUI_Units_Remaining_Text  matlab.ui.control.Label
        Sierra_GUI_Gas_Text             matlab.ui.control.Label
        SetpointEditFieldLabel          matlab.ui.control.Label
        Sierra_GUI_Set_Point_UserValue  matlab.ui.control.NumericEditField
        TransferAmountEditFieldLabel    matlab.ui.control.Label
        Sierra_GUI__Transfer_Amount_UserValue  matlab.ui.control.NumericEditField
        Sierra_GUI_Transfer_Button      matlab.ui.control.Button
        Sierra_GUI_Transfer_Cancel_Button  matlab.ui.control.Button
        TransferingLampLabel            matlab.ui.control.Label
        Sierra_GUI_Transfering_Lamp     matlab.ui.control.Lamp
        Sierra_GUI_Transfer_Amount_Units_Text  matlab.ui.control.Label
        Sierra_GUI_SetPoint_Units_Text  matlab.ui.control.Label
        Sierra_GUI_Estimate_Pressure_Button  matlab.ui.control.Button
        Sierra_GUI_Purge_Button         matlab.ui.control.StateButton
        EGun_Control_Tab                matlab.ui.container.Tab
        Pico_Control_Tab                matlab.ui.container.Tab
        Arduino_Control_Tab             matlab.ui.container.Tab
        Ambient_Display_K_Units_Text    matlab.ui.control.Label
        AT_Label                        matlab.ui.control.Label
        AmbientTemperatureLabel         matlab.ui.control.Label
        Auto_Connect_Instruments_Panel  matlab.ui.container.Panel
        AvailibleInstrumentsListBoxLabel  matlab.ui.control.Label
        Availible_Instruments_List_Box  matlab.ui.control.ListBox
        Auto_Connect_Button             matlab.ui.control.Button
        Auto_Connect_Cancel_Button      matlab.ui.control.Button
        Configure_Panel                 matlab.ui.container.Panel
        Configure_Save_Button           matlab.ui.control.Button
        Configure_Cancel_Button         matlab.ui.control.Button
        Configure_Tab_Group             matlab.ui.container.TabGroup
        Prefrences_Tab                  matlab.ui.container.Tab
        UpdateIntervalSpinnerLabel      matlab.ui.control.Label
        Log_Update_Interval_Spinner     matlab.ui.control.Spinner
        Sierra_Configuration_Tab        matlab.ui.container.Tab
        DefaultComportDropDownLabel     matlab.ui.control.Label
        Sierra_Default_Comport_DropDown  matlab.ui.control.DropDown
        GasDropDownLabel                matlab.ui.control.Label
        Sierra_Gas_DropDown             matlab.ui.control.DropDown
        UnitsDropDownLabel              matlab.ui.control.Label
        Sierra_Units_DropDown           matlab.ui.control.DropDown
        Lakeshore_Configuration_Tab     matlab.ui.container.Tab
        DefaultComportDropDownLabel_2   matlab.ui.control.Label
        Lakeshore_Default_Comport_DropDown  matlab.ui.control.DropDown
        VQM_Configuration_Tab           matlab.ui.container.Tab
        DefaultComportDropDownLabel_3   matlab.ui.control.Label
        VQM__Default_Comport_DropDown   matlab.ui.control.DropDown
        ArduinoTab                      matlab.ui.container.Tab
        DefaultComportDropDownLabel_4   matlab.ui.control.Label
        Arduino_Default_Comport_DropDown  matlab.ui.control.DropDown
        PT_Display_Panel                matlab.ui.container.Panel
        PT_Display_Pressure_Label       matlab.ui.control.Label
        PT_Display_Pressure_Text        matlab.ui.control.Label
        PT_Display_A_Label              matlab.ui.control.Label
        PT_Display_C_Label              matlab.ui.control.Label
        PT_Display_Temperature_A_Text   matlab.ui.control.Label
        PT_Display_Temperature_C_Text   matlab.ui.control.Label
        PT_Display_Temperature_Label    matlab.ui.control.Label
        PT_Display_Torr_Label           matlab.ui.control.Label
        PT_Display_A_Units_Text         matlab.ui.control.Label
        PT_Display_C_Units_Text         matlab.ui.control.Label
        PT_Display_dPdt_Label           matlab.ui.control.Label
        PT_Display_dPdt_Text            matlab.ui.control.Label
        PT_Display_Torr_Label_2         matlab.ui.control.Label
        PlotConfigurationPanel          matlab.ui.container.Panel
        XAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        YAxisDropDown                   matlab.ui.control.DropDown
        ApplyButton                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        scaleDropDownLabel              matlab.ui.control.Label
        scaleDropDown                   matlab.ui.control.DropDown
        GridCheckBox                    matlab.ui.control.CheckBox
        MovingAvgCheckBox               matlab.ui.control.CheckBox
        SampleSizeEditFieldLabel        matlab.ui.control.Label
        SampleSizeEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        File_Path;
        Log_Path;
        Found_Serial_Instruments;
        Active_Serial_Instruments
        Instrument_Function_Array;
        % Instrument Classes
        Arduino;
        Arduino_Active = 0;
        Lakeshore;
        Lakeshore_Active = 0;
        Sierra;
        Sierra_Active = 0;
        Sierra_Transfer_Active = 0;
        VQM;
        VQM_Active = 0;
        %Log info
        
        Log_Timer;
        Log_Period;
        Log_Date_Time;
        Log_Epoch_Time;
        Log_Data;
        Date_Time;
        Epoch_Time;
        Default_Comports_File_Path;
        BugFinder = 0;
        Log_Filename;
        Main_Subplot_Top_Active = 0;
        Top_Subplot;
        Top_Subplot_Function;
        A_Plot_X;
        A_Plot_Y;
        % Description
    end
    
    properties (Access = private)
        Update_Functions; % Description
        MGMT;
        Large_Axes;
        Top_Sub_Axes;
        Bottom_Sub_Axes;
    end
    
    methods (Access = private)
        
        function  Exit_Program(app)
            stop(timerfindall);
            if app.Sierra_Active == 1
                
                app.Sierra_Close();
            end
            app.Close_Stray_Serial_Objects( );
            stop(timerfindall);
            delete(app.Log_Timer);
            delete(app);
        end
        
        function  Add_Paths(app)
            Fullpath = mfilename('fullpath');  % Returns the fullpath to this function
            filepath = fileparts(Fullpath);  % Returns path alone
            addpath(genpath(filepath)) ;        % Adds all subdirectories
            app.File_Path = filepath;
        end
        
        function Auto_Connect_Instruments(app)
               %app.Quick_Connect()
              app.Set_Comports();
              app.Found_Serial_Instruments = Find_Serial_Instruments();
              app.Availible_Instruments_List_Box.Items = app.Found_Serial_Instruments;  % Finds availible serial instruments                   
              app.Auto_Connect_Instruments_Panel.Visible = 'on';
        end
        
        function  Set_Defaults(app)
            app.Log_Period = app.Log_Update_Interval_Spinner.Value;
  
            
            Instrument_Names = ["Lakeshore" "Sierra" "VQM" "Arduino"];
            
            a = string(app.Lakeshore_Default_Comport_DropDown.Value);
            b = string(app.Sierra_Default_Comport_DropDown.Value);
            c = string(app.VQM__Default_Comport_DropDown.Value);
            d = string(app.Arduino_Default_Comport_DropDown.Value);
            Default_Values_Table = table(a,b,c,d,'VariableNames', Instrument_Names);
            writetable(Default_Values_Table, app.Default_Comports_File_Path, 'Delimiter', '\t');
             
        end
    
        function Load_Defaults(app)
           
            Default_Comports = readtable(app.Default_Comports_File_Path);
            app.Lakeshore_Default_Comport_DropDown.Value = Default_Comports.Lakeshore{1};
            app.Sierra_Default_Comport_DropDown.Value = Default_Comports.Sierra{1};
            app.VQM__Default_Comport_DropDown.Value = Default_Comports.VQM{1};
            app.Arduino_Default_Comport_DropDown.Value = Default_Comports.Arduino{1};
        end
    
        function Set_Comports(app)
            Sierra_MFC_Set_Comport(app.Sierra,app.Sierra_Default_Comport_DropDown.Value);
            app.Lakeshore.Set_Comport(app.Lakeshore_Default_Comport_DropDown.Value);
            VQM_Set_Comport(app.VQM, app.VQM__Default_Comport_DropDown.Value);
            app.Arduino.Set_Comport(app.Arduino_Default_Comport_DropDown.Value);
        end
        
    
        function Connect_Serial_Instruments(app, Instruments)
            app.Set_Defaults();
           
            for idx = 1:length(Instruments)
    
                switch Instruments(idx)
                case 'Sierra Instruments Microtrak Controller'
                    app.Sierra.Serial_Create(); % Which is faster app.?
                    app.Sierra.Set_Stream('Echo');
                    app.Sierra_Active = 1;
                    app.Sierra_Activated();
                    app.Sierra_Initialize();
                    %app.Sierra_Set_Config();                  
                    disp('Connecting to Flow Controller')
                   
                 case 'Granvillie Phillips VQM'
                     disp('Connectiong to VQM');
                     app.VQM =Granville_Phillips_835_VQM();
                      VQM_Set_Comport(app.VQM, app.VQM__Default_Comport_DropDown.Value);
                     VQM_Serial_Create(app.VQM);
                     app.VQM_Active = 1;
                case 'Lakeshore 336 Temperature Controller'
                     
                     disp('Connecting to Lakeshore 336 Temperature Controller')
                     app.Lakeshore = Lakeshore_336_Temperature_Controller();
                     
                     app.Lakeshore.Set_Comport(app.Lakeshore_Default_Comport_DropDown.Value);
                     Lakeshore_Serial_Create(app.Lakeshore);
                     app.Lakeshore_Active = 1;
                     
                 case 'Arduino' 
                        disp('Connecting to Arduino')
                        app.Arduino.Set_Comport(app.Arduino_Default_Comport_DropDown.Value);
                        app.Arduino.Serial_Create();
                       
                        app.Arduino_Active = 1;
                        %f = @app.Arduino_Update;
                        
                        
                otherwise
                      msgbox('No Instruments selected');
                end
            end 
            
        end


        function  Update_Time_Date(app)
            
            app.Date_Time = datetime;
            app.Epoch_Time = datenum(app.Date_Time);            
            app.Log_Date_Time = [app.Log_Date_Time , app.Date_Time];
            app.Log_Epoch_Time = [app.Log_Epoch_Time , app.Epoch_Time];
        end


        function Close_Stray_Serial_Objects( app)
            %  Close_Stray_Serial_Objects
            Serial_Objects = instrfindall;
            if isempty(Serial_Objects)
                 return;
            else
                for idx = length(Serial_Objects)
                    if  strcmp(Serial_Objects(idx).status,'open')
                         fclose(instrfindall);
                         delete(instrfindall);            
                    else
                        delete(instrfindall);        
                     end
                end
            end
        end
        
        function  Save_Log_Data(app)
            [app.Log_Filename, app.Log_Path] = uiputfile('.dat', 'Choose Location and Filename');
            app.Log_Instrument_Data();
             
            writetable(app.Log_Data,[app.Log_Path , app.Log_Filename], 'Delimiter', '\t');
            app.Exit_Program();
        end
        
        function  LakeShore_Activated(app)
            if app.Lakeshore_Active == 1
                app.Lakeshore_Default_Comport_DropDown.Enable = 'off';
                app.MGMT.Add_Update_Function(@app.Lakeshore_Update);
            end
        end
        
        function Sierra_Activated(app)
           if app.Sierra_Active == 1
              app.Sierra_Default_Comport_DropDown.Enable = 'off'; 
              app.MGMT.Add_Update_Function(@app.Sierra_Update);
             
           end
        end
        
        function VQM_Activated(app)
           if app.VQM_Active == 1
              app.VQM__Default_Comport_DropDown.Enable = 'off';    
              app.MGMT.Add_Update_Function(@app.VQM_Timer_Update);
           end
        end


        function Sierra_Stop_Transfer(app)
             if app.Sierra_Transfer_Active == 1
                app.Sierra.Set_Setpoint(0.000);
                app.Sierra_Transfer_Active = 0;
                app.Sierra_GUI_Transfering_Lamp.Color = [0.50 0.65 0.50];
             end
        end
        
        function Bug_Finder(app)
            app.BugFinder = app.BugFinder + 1;
                disp('The Bugfinder is at ')
                disp(app.BugFinder)
        end
        
        function Arduino_Plot(app)
            
               app.Top_Subplot.YData = cell2mat(app.Arduino.Temperature_Log);  
            
        end
    
        function Lakeshore_A_Plot(app)   
               app.Top_Subplot.YData = cell2mat(app.Lakeshore.A_Temperature_Log);   
        end
    
    function Lakeshore_C_Plot(app)   
               app.Top_Subplot.YData = cell2mat(app.Lakeshore.C_Temperature_Log);   
        end
    
        function Lakeshore_A_Plot_Avg(app)
            avg = Moving_Average( cell2mat(app.Lakeshore.A_Temperature_Log),app.SampleSizeEditField.Value);
            app.Top_Subplot.YData = avg';
        end
    
        function Lakeshore_C_Plot_Avg(app)
            avg = Moving_Average( cell2mat(app.Lakeshore.C_Temperature_Log),app.SampleSizeEditField.Value);
            app.Top_Subplot.YData = avg';
        end
    
    
         function VQM_Plot(app)   
               app.Top_Subplot.YData = cell2mat(app.VQM.Log_Pressure);      
         end

        function VQM_Plot_Avg(app)
            avg = Moving_Average( cell2mat(app.VQM.Log_Pressure),app.SampleSizeEditField.Value);
            app.Top_Subplot.YData = avg';
        end
    
        function Plot_Generator_Switch(app)
           
            switch app.YAxisDropDown.Value
                case 'Arduino'                    
                     app.Top_Subplot =  plot(app.Main_Sub_Axes_Top,app.Log_Date_Time, cell2mat(app.Arduino.Temperature_Log) );
                     title(app.Main_Sub_Axes_Top, 'Ambient Temperature');
                     xlabel(app.Main_Sub_Axes_Top, 'Time');
                     ylabel(app.Main_Sub_Axes_Top, 'Temperature (K)');
                     app.Top_Subplot_Function = @app.Arduino_Plot;
                     
                case 'Sierra'
                     app.Top_Subplot =  plot(app.Main_Sub_Axes_Top,app.Log_Date_Time, cell2mat(app.Sierra.Flowrate_Log) );
                     title(app.Main_Sub_Axes_Top, 'Flowrate');
                     xlabel(app.Main_Sub_Axes_Top, 'Time');
                     ylabel(app.Main_Sub_Axes_Top, 'Flowrate (sccs)');
                     app.Top_Subplot_Function = @app.Sierra_Plot;
                     
                case 'Lakeshore 336 Temperature Controller'
                  
                     app.Top_Subplot =  plot(app.Main_Sub_Axes_Top,app.Log_Date_Time, cell2mat(app.Lakeshore.A_Temperature_Log) );
                     title(app.Main_Sub_Axes_Top, 'Temperature');
                     xlabel(app.Main_Sub_Axes_Top, 'Time');
                     ylabel(app.Main_Sub_Axes_Top, 'Temperature (K)');
                     if app.MovingAvgCheckBox.Value == 1
                         app.Top_Subplot_Function = @app.Lakeshore_A_Plot_Avg;
                         
                     else
                         app.Top_Subplot_Function = @app.Lakeshore_A_Plot;
                     
                     end
                case 'Granvillie Phillips VQM'
                     app.Top_Subplot =  plot(app.Main_Sub_Axes_Top,app.Log_Date_Time, cell2mat(app.VQM.Log_Pressure) );
                     title(app.Main_Sub_Axes_Top, 'Pressure');
                     xlabel(app.Main_Sub_Axes_Top, 'Time');
                     ylabel(app.Main_Sub_Axes_Top, 'Pressure (Torr)');
                      if app.MovingAvgCheckBox.Value == 1
                            app.Top_Subplot_Function = @app.VQM_Plot_Avg;
                      else
                           app.Top_Subplot_Function = @app.VQM_Plot;
                     
                      end
            end
        end
        
        function Open_File(app)
           Analysis
           app.Exit_Program();
        end
        
        function Create_Timer(app)
            app.Log_Timer = timer('ExecutionMode', 'fixedRate', 'Period', app.Log_Period );  
            app.Log_Timer.BusyMode = 'queue';
            app.Log_Timer.TimerFcn = @(~,~) app.Timer_Update;
        end
        
        function  Create_Classes(app)
            app.Lakeshore = Lakeshore_336_Temperature_Controller();
            app.Sierra = Sierra_MicroTrak_c101();
            app.VQM = Granville_Phillips_835_VQM();
            app.Arduino = ArduinoDHTSerial();
            app.MGMT = Data_Management();
            app.Large_Axes = Plot_Management();
            app.Top_Sub_Axes = Plot_Management();
            app.Bottom_Sub_Axes =Plot_Management();
        end
    end
    
    methods (Access = public)
        
        function Timer_Update(app)
            app.Update_Time_Date();
            if app.Lakeshore_Active == 1
                app.Lakeshore_Update();               
            end
            if app.Sierra_Active == 1 
                    app.Sierra_Update();
            end
            if app.VQM_Active == 1
               app.VQM_Timer_Update(); 
            end
            if app.Arduino_Active == 1
               app.Arduino_Update(); 
            end


                if app.Main_Subplot_Top_Active == 1                    
                      switch app.XAxisDropDown.Value
                         case 'Time'
                             app.Top_Subplot.XData = app.Log_Date_Time;
                      end
                    
                        
                    app.Top_Subplot_Function();

                end

%              plot(app.Main_Sub_Axes_Bottom, app.Log_Date_Time, cell2mat(app.Lakeshore.C_Temperature_Log));
        end
    
        function Lakeshore_Update(app)
            Lakeshore_Update_Data(app.Lakeshore);
            Add_Log_Data(app.Lakeshore);
            app.PT_Display_Temperature_A_Text.Text = string(app.Lakeshore.A_Current_Temperature);
            app.PT_Display_Temperature_C_Text.Text = string(app.Lakeshore.C_Current_Temperature);
        end

        function Sierra_Update(app)
                
                app.Sierra.Update_Data();
               
                app.Sierra_GUI_Flowrate_Text.Text = string(app.Sierra.Flowrate);
               
                
                if app.Sierra_Transfer_Active ==1
                   % disp('CheckPoint Reached')
                  % disp('Mol Remain')
                  % disp(string(app.Sierra.Mol_Remaining))
                    app.Sierra_GUI_Mol_Remaining_Text.Text = string(app.Sierra.Mol_Remaining);
                   % disp('Mol transferred')
                    %disp(string (app.Sierra.Mol_Transfered))
                   app.Sierra_GUI_Mol_Transferred_Text.Text = string (app.Sierra.Mol_Transfered);
                    if app.Sierra.Volume_Transfered >= app.Sierra.Volume_Transfer_Amount
                       app.Sierra_Stop_Transfer(); 
                   end
                end
           
        end
    
        function Sierra_Close(app)
           app.Sierra.Set_Setpoint(0.000);
           app.Sierra.Set_Stream('On') ;
            
        end
        function VQM_Timer_Update(app)
           VQM_Update(app.VQM);      
           app.PT_Display_Pressure_Text.Text = string(app.VQM.Current_Pressure);
          if length(app.VQM.Log_Pressure(:)) > 15
              app.Bug_Finder();
              app.VQM_DPDT();
              
          end
        end
    
    
        function VQM_DPDT(app)
            sample_size = 10;
             Output_Data =( cell2mat(app.VQM.Log_Pressure(end)) - cell2mat(app.VQM.Log_Pressure(end-sample_size)))/  sample_size;
           app.PT_Display_dPdt_Text.Text = string(Output_Data);
            
            
        end
        function Arduino_Update(app)
         %   disp('arduino update')
            app.Arduino.Update_Data();
            app.AT_Label.Text = string(app.Arduino.Current_Temperature);
           % disp(string(app.Arduino.Current_Temperature))
            %disp(app.Ambient_Display_Temperature.Text)
        end
        function Sierra_Initialize(app)
          app.Sierra.Set_Gas(str2double(app.Sierra_Gas_DropDown.Value));
          app.Sierra_GUI_Gas_Text.Text  = string(app.Sierra.Gas_String);
          Set_Units(app.Sierra,str2double(app.Sierra_Units_DropDown.Value));
          
         
           app.Sierra_GUI_Flowrate_Units_Text.Text = string(app.Sierra.Units_String);
        end
        function Sierra_Set_Config(app)  
            if  app.Sierra_Active == 1 & app.Sierra.Setpoint == 0 
                 
               % Set_Gas(app.Sierra, str2double(app.Sierra_Gas_DropDown.Value));
                app.Sierra.Set_Gas(str2double(app.Sierra_Gas_DropDown.Value)); 
                app.Sierra_GUI_Gas_Text.Text  = string(app.Sierra.Gas_String);
                Set_Units(app.Sierra,str2double(app.Sierra_Units_DropDown.Value));
                app.Sierra_GUI_Flowrate_Units_Text.Text = string(app.Sierra.Units_String);
            end
        end
        
        function  Log_Instrument_Data(app)
            
              app.Log_Data = table(app.Log_Date_Time(:), app.Log_Epoch_Time(:) );
              app.Log_Data.Properties.VariableNames = {'DateTime', 'Epoch_Time'};             
            
            if app.Lakeshore_Active == 1
                LakeShore_Data = table( app.Lakeshore.A_Temperature_Log(:), app.Lakeshore.C_Temperature_Log(:) );
                LakeShore_Data.Properties.VariableNames = {'Temperature_A', 'Temperature_C'};
                app.Log_Data = [app.Log_Data, LakeShore_Data];
            end
            if app.Sierra_Active == 1 
                    
            end
            if app.VQM_Active == 1
               Pressure_Data = table( app.VQM.Log_Pressure(:));
               Pressure_Data.Properties.VariableNames = {'Log_Pressure'}; 
               app.Log_Data = [app.Log_Data, Pressure_Data];
            end
            if app.Arduino_Active == 1
               
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %% Startup Function: Adds the paths of instruments and functions
           %% Creates intances of classes NOTE: May change class intialization to later point
           %% Sets defaults file's path, Load and Sets defaults,(Comports, gas, units...)
           %% Creates timer and specifies timer function
           %% After this function runs the program is idle. 
            app.Add_Paths();         
            app.Default_Comports_File_Path = [app.File_Path ,'\Default_Comports.dat'];
            app.Create_Classes();
            app.Load_Defaults();
            app.Set_Defaults();
            app.Create_Timer();
        end

        % Callback function: Exit_Project_Menu_Bar, LC_Main_Window
        function LC_Main_Window_Close_Request(app, event)
           %stop(app.Log_Timer);
            msg = 'Are you sure you want to exit?';
            title = 'Close Project';
            Selection = uiconfirm(app.LC_Main_Window,msg,title,...
            'Options',{'Save','Exit','Cancel'},...
            'DefaultOption',1,'CancelOption',3);
        switch Selection
            case 'Save'
                % Redirect to save file function
                app.Save_Log_Data();
            case 'Exit'
                % Redirect to Exit function
                app.Exit_Program();
            case 'Cancel'
                %Do nothing, Returns to program
                %start(app.Log_Timer);
        end
            
            
        end

        % Menu selected function: New_Project_Menu_Bar
        function New_Project_Menu_Bar_Selected(app, event)
            % New Project Selected
               msg = 'Choose Auto to view instruments by their associated comport, or Configure to set comports.';
            title = 'Connect to Instruments';
            Selection = uiconfirm(app.LC_Main_Window,msg,title,...
            'Options',{'Auto','Configure','Cancel'},...
            'DefaultOption',1,'CancelOption',3);
        switch Selection
            case 'Auto'
                % Redirect to Auto dectect Instruments
                app.Auto_Connect_Instruments();
            case 'Configure'
                % Redirect to Configure to set comport
                 app.Configure_Panel.Visible = 'on';
            case 'Cancel'
                % Do nothing, Returns to program
        end
        end

        % Button pushed function: Auto_Connect_Button
        function Auto_Connect_Button_Pushed(app, event)
            if size(app.Availible_Instruments_List_Box.Value) == 0
                msgbox('Please Select an Instrument');
            else
                
                app.Auto_Connect_Instruments_Panel.Visible = 'off';
                app.Control_Tabs.Visible = 'on';
                app.PT_Display_Panel.Visible = 'on';
               % app.Main_Sub_Axes_Top.Visible = 'on';
                %app.Main_Sub_Axes_Bottom.Visible = 'on';
                app.Connect_Serial_Instruments(string(app.Availible_Instruments_List_Box.Value));
                app.Active_Serial_Instruments = string(app.Availible_Instruments_List_Box.Value);
%                 app.LogTimer = timer('ExecutionMode', 'fixedRate', 'Period', app.Log_Period );         
%                 app.LogTimer.TimerFcn = @(~,~) app.Timer_Update;
                start(app.Log_Timer); 
                
            end
        end

        % Button pushed function: Auto_Connect_Cancel_Button
        function Auto_Connect_Cancel_Button_Pushed(app, event)
            app.Auto_Connect_Instruments_Panel.Visible = 'off';
        end

        % Button pushed function: Configure_Save_Button
        function Configure_Save_Button_Pushed(app, event)
            
                app.Set_Defaults();
                app.Configure_Panel.Visible = 'off';
              if app.Sierra.Setpoint == 0 & app.Sierra_Active == 1
                app.Sierra_Set_Config();
              end            
        end

        % Menu selected function: Configure_Project_Menu_Bar
        function Configure_Project_Menu_Bar_Selected(app, event)
            
            app.Configure_Panel.Visible = 'on';
        end

        % Button pushed function: Configure_Cancel_Button
        function Configure_Cancel_ButtonPushed(app, event)
            app.Configure_Panel.Visible = 'off';
        end

        % Menu selected function: Save_Project_Menu_Bar
        function Save_Project_Menu_BarMenuSelected(app, event)
            app.Save_Log_Data();
        end

        % Button pushed function: Sierra_GUI_Transfer_Button
        function Sierra_GUI_Transfer_ButtonPushed(app, event)
            if app.Sierra_GUI_Set_Point_UserValue.Value ~= 0 && app.Sierra_GUI__Transfer_Amount_UserValue.Value ~= 0
                app.Sierra.Mol_Transfer_Amount = app.Sierra_GUI__Transfer_Amount_UserValue.Value*1E-6;
                app.Sierra.Specify_Micro_Mol();
                app.Sierra.Set_Setpoint(app.Sierra_GUI_Set_Point_UserValue.Value)
                app.Sierra_GUI_Transfering_Lamp.Color = [.31 .92 .31];
                app.Sierra_Transfer_Active = 1;
            end
        end

        % Button pushed function: Sierra_GUI_Transfer_Cancel_Button
        function Sierra_GUI_Transfer_Cancel_ButtonPushed(app, event)
            if app.Sierra_Transfer_Active == 1
                app.Sierra_Stop_Transfer();
            end
        end

        % Button pushed function: 
        % Sierra_GUI_Estimate_Pressure_Button
        function Sierra_GUI_Estimate_Pressure_ButtonPushed(app, event)
            if app.VQM_Active == 1 & app.Sierra_Active ==1
                if app.Sierra_GUI__Transfer_Amount_UserValue.Value ~= 0 
                    Expected_Pressure_Chamber_mol(app.Sierra.Volume_Transfer_Amount, app.VQM.Current_Pressure)           
                end
            end
        end

        % Button pushed function: Lakeshore_300K_Button
        function Lakeshore_300K_ButtonPushed(app, event)
            %app.Lakeshore.Set_All_Temperatures_Kelvin(300, Range);
        end

        % Button pushed function: Lakeshore_10K_Button
        function Lakeshore_10K_ButtonPushed(app, event)
            %app.Lakeshore.Set_All_Temperatures_Kelvin(10, Range);
        end

        % Button pushed function: Lakeshore_150K_Button
        function Lakeshore_150K_ButtonPushed(app, event)
            %app.Lakeshore.Set_All_Temperatures_Kelvin(150, Range);            
        end

        % Button pushed function: 
        % Lakeshore_GUI_Set_Temperature_Button
        function Lakeshore_GUI_Set_Temperature_ButtonPushed(app, event)
            if app.Lakeshore_Active ==1
               if app.Lakeshore_GUI_Link_Heaters_CheckBox.Value ==1
                    Temperature = app.Lakeshore_Setpoint_A.Value;
                     Range =app.Lakeshore_GUI_Heater_Range_A_DropDown.Value;
                     disp(Temperature)
                     disp(Range)
                     
                    app.Lakeshore.Set_All_Temperatures_Kelvin(Temperature)
                   app.Lakeshore.Set_Heater_Range_All(Range)
               else
                   Temperature_A = app.Lakeshore_Setpoint_A.Value;
                   Range_A =app.Lakeshore_GUI_Heater_Range_A_DropDown.Value;
                   Temperature_C = app.Lakeshore_Setpoint_C.Value;
                    Range_C =app.Lakeshore_GUI_Heater_Range_C_DropDown.Value;
                    disp(Temperature_A);
                  %  disp(Temperature_C);
                    app.Lakeshore.Set_Temperature_Setpoint_1(Temperature_A);
                    app.Lakeshore.Set_Temperature_Setpoint_2(Temperature_C);
                    app.Lakeshore.Set_Heater_Range_1(Range_A);
                    app.Lakeshore.Set_Heater_Range_2(Range_C);
               end
            end
        end

        % Menu selected function: ConfigurationMenu
        function ConfigurationMenuSelected(app, event)
             app.PlotConfigurationPanel.Visible = 'on';
           %disp(app.Active_Serial_Instruments)
             app.YAxisDropDown.Items = app.Active_Serial_Instruments;
             app.XAxisDropDown.Items = ["Time"  "Last Minute"];
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            cla(app.Main_Sub_Axes_Top, 'reset')
            app.Main_Sub_Axes_Top.Visible = 'on';
            app.Main_Subplot_Top_Active = 1;
            
            app.PlotConfigurationPanel.Visible ='off';
            
            if app.GridCheckBox.Value == 1
                 grid(app.Main_Sub_Axes_Top, 'on');
            elseif app.GridCheckBox.Value == 0
                 grid(app.Main_Sub_Axes_Top, 'off')
            end


            switch app.scaleDropDown.Value
                case 'Linear'
                    app.Main_Sub_Axes_Top.YScale = 'linear';
                
                case 'Log'
                    app.Main_Sub_Axes_Top.YScale = 'log';
            end
            
           switch app.XAxisDropDown.Value
               
               case 'Last Minute'
                   limit = (datenum(app.Main_Sub_Axes_Top.XLim(2))*86400 - 30)/86400;
                    app.Main_Sub_Axes_Top.XLim = [datestr(limit) app.Main_Sub_Axes_Top.XLim(2)];

           end
            app.Plot_Generator_Switch();


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.PlotConfigurationPanel.Visible = 'off';
        end

        % Value changed function: MovingAvgCheckBox
        function MovingAvgCheckBoxValueChanged(app, event)
            value = app.MovingAvgCheckBox.Value;
            if value ==1
                app.SampleSizeEditField.Editable = 'on';
                app.SampleSizeEditField.Limits = [2, length(app.Log_Epoch_Time)];
            elseif value == 0
                app.SampleSizeEditField.Editable ='off';
            end
        end

        % Menu selected function: Open_Project_Menu_Bar
        function Open_Project_Menu_BarSelected(app, event)
            app.Open_File();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LC_Main_Window = uifigure('Visible', 'off');
            app.LC_Main_Window.Name = 'Lab Command';
            app.LC_Main_Window.CloseRequestFcn = createCallbackFcn(app, @LC_Main_Window_Close_Request, true);
            app.Project_Menu_Bar = uimenu(app.LC_Main_Window);
            app.Project_Menu_Bar.Text = 'Project';
            app.New_Project_Menu_Bar = uimenu(app.Project_Menu_Bar);
            app.New_Project_Menu_Bar.MenuSelectedFcn = createCallbackFcn(app, @New_Project_Menu_Bar_Selected, true);
            app.New_Project_Menu_Bar.Separator = 'on';
            app.New_Project_Menu_Bar.Text = 'New';
            app.Open_Project_Menu_Bar = uimenu(app.Project_Menu_Bar);
            app.Open_Project_Menu_Bar.MenuSelectedFcn = createCallbackFcn(app, @Open_Project_Menu_BarSelected, true);
            app.Open_Project_Menu_Bar.Separator = 'on';
            app.Open_Project_Menu_Bar.Text = 'Open';
            app.Save_Project_Menu_Bar = uimenu(app.Project_Menu_Bar);
            app.Save_Project_Menu_Bar.MenuSelectedFcn = createCallbackFcn(app, @Save_Project_Menu_BarMenuSelected, true);
            app.Save_Project_Menu_Bar.Separator = 'on';
            app.Save_Project_Menu_Bar.Text = 'Save ';
            app.Configure_Project_Menu_Bar = uimenu(app.Project_Menu_Bar);
            app.Configure_Project_Menu_Bar.MenuSelectedFcn = createCallbackFcn(app, @Configure_Project_Menu_Bar_Selected, true);
            app.Configure_Project_Menu_Bar.Separator = 'on';
            app.Configure_Project_Menu_Bar.Text = 'Configure';
            app.Exit_Project_Menu_Bar = uimenu(app.Project_Menu_Bar);
            app.Exit_Project_Menu_Bar.MenuSelectedFcn = createCallbackFcn(app, @LC_Main_Window_Close_Request, true);
            app.Exit_Project_Menu_Bar.Separator = 'on';
            app.Exit_Project_Menu_Bar.Text = 'Exit';
            app.Plot_Menu_Bar = uimenu(app.LC_Main_Window);
            app.Plot_Menu_Bar.Text = 'Plot ';
            app.ConfigurationMenu = uimenu(app.Plot_Menu_Bar);
            app.ConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigurationMenuSelected, true);
            app.ConfigurationMenu.Text = 'Configuration';
            app.Main_Large_Axes = uiaxes(app.LC_Main_Window);
            title(app.Main_Large_Axes, 'Title')
            xlabel(app.Main_Large_Axes, 'X')
            ylabel(app.Main_Large_Axes, 'Y')
            app.Main_Large_Axes.Visible = 'off';
            app.Main_Sub_Axes_Top = uiaxes(app.LC_Main_Window);
            title(app.Main_Sub_Axes_Top, 'Title')
            xlabel(app.Main_Sub_Axes_Top, 'X')
            ylabel(app.Main_Sub_Axes_Top, 'Y')
            app.Main_Sub_Axes_Top.Visible = 'off';
            app.Main_Sub_Axes_Bottom = uiaxes(app.LC_Main_Window);
            title(app.Main_Sub_Axes_Bottom, 'Title')
            xlabel(app.Main_Sub_Axes_Bottom, 'X')
            ylabel(app.Main_Sub_Axes_Bottom, 'Y')
            app.Main_Sub_Axes_Bottom.Visible = 'off';
            app.Control_Tabs = uitabgroup(app.LC_Main_Window);
            app.Lakeshore_Control_Tab = uitab(app.Control_Tabs);
            app.Lakeshore_Control_Tab.Title = 'Temperature';
            app.Lakeshore_GUI_Link_Heaters_CheckBox = uicheckbox(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Link_Heaters_CheckBox.Text = 'Link Heaters';
            app.Lakeshore_GUI_Set_Temperature_Button = uibutton(app.Lakeshore_Control_Tab, 'push');
            app.Lakeshore_GUI_Set_Temperature_Button.ButtonPushedFcn = createCallbackFcn(app, @Lakeshore_GUI_Set_Temperature_ButtonPushed, true);
            app.Lakeshore_GUI_Set_Temperature_Button.Text = 'Set Temperature';
            app.Lakeshore_GUI_Setpoint_Label = uilabel(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Setpoint_Label.Text = 'Setpoint';
            app.Lakeshore_GUI_Setpoint_A_Units_Text = uilabel(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Setpoint_A_Units_Text.Text = 'K';
            app.Lakeshore_GUI_Heater_Range_A_DropDown = uidropdown(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Heater_Range_A_DropDown.Items = {'Off', 'Low', 'Medium', 'High'};
            app.Lakeshore_GUI_Heater_Range_A_DropDown.ItemsData = {'0', '1', '2', '3'};
            app.Lakeshore_GUI_Heater_Range_A_DropDown.Value = '0';
            app.Lakeshore_GUI_Heater_Range_Label = uilabel(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Heater_Range_Label.Text = 'Heater Range';
            app.Lakeshore_GUI_Setpoint_C_Units_Text = uilabel(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Setpoint_C_Units_Text.Text = 'K';
            app.Lakeshore_GUI_Heater_Range_C_DropDown = uidropdown(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Heater_Range_C_DropDown.Items = {'Off', 'Low', 'Medium', 'High'};
            app.Lakeshore_GUI_Heater_Range_C_DropDown.ItemsData = {'0', '1', '2', '3'};
            app.Lakeshore_GUI_Heater_Range_C_DropDown.Value = '0';
            app.Lakeshore_300K_Button = uibutton(app.Lakeshore_Control_Tab, 'push');
            app.Lakeshore_300K_Button.ButtonPushedFcn = createCallbackFcn(app, @Lakeshore_300K_ButtonPushed, true);
            app.Lakeshore_300K_Button.Text = '300K';
            app.Lakeshore_10K_Button = uibutton(app.Lakeshore_Control_Tab, 'push');
            app.Lakeshore_10K_Button.ButtonPushedFcn = createCallbackFcn(app, @Lakeshore_10K_ButtonPushed, true);
            app.Lakeshore_10K_Button.Text = '10K';
            app.Lakeshore_150K_Button = uibutton(app.Lakeshore_Control_Tab, 'push');
            app.Lakeshore_150K_Button.ButtonPushedFcn = createCallbackFcn(app, @Lakeshore_150K_ButtonPushed, true);
            app.Lakeshore_150K_Button.Text = '150K';
            app.Lakeshore_GUI_A_Label = uilabel(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_A_Label.Text = 'A:';
            app.Lakeshore_GUI_C_Label = uilabel(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_C_Label.Text = 'C:';
            app.Lakeshore_GUI_Quick_Set_Label = uilabel(app.Lakeshore_Control_Tab);
            app.Lakeshore_GUI_Quick_Set_Label.Text = 'Temperature Quick Set';
            app.Lakeshore_Setpoint_A = uieditfield(app.Lakeshore_Control_Tab, 'numeric');
            app.Lakeshore_Setpoint_A.Limits = [0 310];
            app.Lakeshore_Setpoint_C = uieditfield(app.Lakeshore_Control_Tab, 'numeric');
            app.Lakeshore_Setpoint_C.Limits = [0 310];
            app.Sierra_Control_Tab = uitab(app.Control_Tabs);
            app.Sierra_Control_Tab.Title = 'Mass Flow';
            app.Sierra_GUI_Flowrate_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Flowrate_Text.Text = '0';
            app.Sierra_GUI_Flowrate_Label = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Flowrate_Label.Text = 'Flow Rate';
            app.Sierra_GUI_Flowrate_Units_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Flowrate_Units_Text.Text = 'sccm/s';
            app.Sierra_GUI_Mol_Transferred_Label = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Mol_Transferred_Label.Text = 'Transferred';
            app.Sierra_GUI_Mol_Transferred_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Mol_Transferred_Text.Text = '0';
            app.Sierra_GUI_Units_Transfered_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Units_Transfered_Text.Text = ' Umol';
            app.Sierra_GUI_Mol_Remianing_Label = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Mol_Remianing_Label.Text = ' Remaing';
            app.Sierra_GUI_Mol_Remaining_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Mol_Remaining_Text.Text = '0';
            app.Sierra_GUI_Units_Remaining_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Units_Remaining_Text.Text = ' Umol';
            app.Sierra_GUI_Gas_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Gas_Text.Text = 'Gas';
            app.SetpointEditFieldLabel = uilabel(app.Sierra_Control_Tab);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.Sierra_GUI_Set_Point_UserValue = uieditfield(app.Sierra_Control_Tab, 'numeric');
            app.Sierra_GUI_Set_Point_UserValue.Limits = [0 0.067];
            app.TransferAmountEditFieldLabel = uilabel(app.Sierra_Control_Tab);
            app.TransferAmountEditFieldLabel.Text = 'Transfer Amount';
            app.Sierra_GUI__Transfer_Amount_UserValue = uieditfield(app.Sierra_Control_Tab, 'numeric');
            app.Sierra_GUI__Transfer_Amount_UserValue.Limits = [0 500];
            app.Sierra_GUI_Transfer_Button = uibutton(app.Sierra_Control_Tab, 'push');
            app.Sierra_GUI_Transfer_Button.ButtonPushedFcn = createCallbackFcn(app, @Sierra_GUI_Transfer_ButtonPushed, true);
            app.Sierra_GUI_Transfer_Button.Text = 'Transfer';
            app.Sierra_GUI_Transfer_Cancel_Button = uibutton(app.Sierra_Control_Tab, 'push');
            app.Sierra_GUI_Transfer_Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Sierra_GUI_Transfer_Cancel_ButtonPushed, true);
            app.Sierra_GUI_Transfer_Cancel_Button.Text = 'Cancel';
            app.TransferingLampLabel = uilabel(app.Sierra_Control_Tab);
            app.TransferingLampLabel.Text = 'Transfering';
            app.Sierra_GUI_Transfering_Lamp = uilamp(app.Sierra_Control_Tab);
            app.Sierra_GUI_Transfer_Amount_Units_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_Transfer_Amount_Units_Text.Text = ' Umol';
            app.Sierra_GUI_SetPoint_Units_Text = uilabel(app.Sierra_Control_Tab);
            app.Sierra_GUI_SetPoint_Units_Text.Text = 'sccm/s';
            app.Sierra_GUI_Estimate_Pressure_Button = uibutton(app.Sierra_Control_Tab, 'push');
            app.Sierra_GUI_Estimate_Pressure_Button.ButtonPushedFcn = createCallbackFcn(app, @Sierra_GUI_Estimate_Pressure_ButtonPushed, true);
            app.Sierra_GUI_Estimate_Pressure_Button.Text = {'Estimate'; 'Pressure'};
            app.Sierra_GUI_Purge_Button = uibutton(app.Sierra_Control_Tab, 'state');
            app.Sierra_GUI_Purge_Button.Text = 'Purge';
            app.EGun_Control_Tab = uitab(app.Control_Tabs);
            app.EGun_Control_Tab.Title = 'E-Gun';
            app.Pico_Control_Tab = uitab(app.Control_Tabs);
            app.Pico_Control_Tab.Title = 'Pico';
            app.Arduino_Control_Tab = uitab(app.Control_Tabs);
            app.Arduino_Control_Tab.Title = 'Arduino';
            app.Ambient_Display_K_Units_Text = uilabel(app.Arduino_Control_Tab);
            app.Ambient_Display_K_Units_Text.Text = 'K';
            app.AT_Label = uilabel(app.Arduino_Control_Tab);
            app.AT_Label.Text = '';
            app.AmbientTemperatureLabel = uilabel(app.Arduino_Control_Tab);
            app.AmbientTemperatureLabel.Text = 'Ambient Temperature';
            app.Auto_Connect_Instruments_Panel = uipanel(app.LC_Main_Window);
            app.Auto_Connect_Instruments_Panel.Title = 'Connect to Devices';
            app.Auto_Connect_Instruments_Panel.Visible = 'off';
            app.AvailibleInstrumentsListBoxLabel = uilabel(app.Auto_Connect_Instruments_Panel);
            app.AvailibleInstrumentsListBoxLabel.Text = 'Availible Instruments';
            app.Availible_Instruments_List_Box = uilistbox(app.Auto_Connect_Instruments_Panel);
            app.Availible_Instruments_List_Box.Items = {};
            app.Availible_Instruments_List_Box.Multiselect = 'on';
            app.Availible_Instruments_List_Box.Value = {};
            app.Auto_Connect_Button = uibutton(app.Auto_Connect_Instruments_Panel, 'push');
            app.Auto_Connect_Button.ButtonPushedFcn = createCallbackFcn(app, @Auto_Connect_Button_Pushed, true);
            app.Auto_Connect_Button.Text = 'Connect';
            app.Auto_Connect_Cancel_Button = uibutton(app.Auto_Connect_Instruments_Panel, 'push');
            app.Auto_Connect_Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Auto_Connect_Cancel_Button_Pushed, true);
            app.Auto_Connect_Cancel_Button.Text = 'Cancel';
            app.Configure_Panel = uipanel(app.LC_Main_Window);
            app.Configure_Panel.Title = 'Configuration';
            app.Configure_Panel.Visible = 'off';
            app.Configure_Save_Button = uibutton(app.Configure_Panel, 'push');
            app.Configure_Save_Button.ButtonPushedFcn = createCallbackFcn(app, @Configure_Save_Button_Pushed, true);
            app.Configure_Save_Button.Text = 'Save';
            app.Configure_Cancel_Button = uibutton(app.Configure_Panel, 'push');
            app.Configure_Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Configure_Cancel_ButtonPushed, true);
            app.Configure_Cancel_Button.Text = 'Cancel';
            app.Configure_Tab_Group = uitabgroup(app.Configure_Panel);
            app.Prefrences_Tab = uitab(app.Configure_Tab_Group);
            app.Prefrences_Tab.Title = 'Prefrences';
            app.UpdateIntervalSpinnerLabel = uilabel(app.Prefrences_Tab);
            app.UpdateIntervalSpinnerLabel.Text = 'Update Interval';
            app.Log_Update_Interval_Spinner = uispinner(app.Prefrences_Tab);
            app.Log_Update_Interval_Spinner.Limits = [1 60];
            app.Log_Update_Interval_Spinner.Value = 1;
            app.Sierra_Configuration_Tab = uitab(app.Configure_Tab_Group);
            app.Sierra_Configuration_Tab.Title = 'MicroTrak';
            app.DefaultComportDropDownLabel = uilabel(app.Sierra_Configuration_Tab);
            app.DefaultComportDropDownLabel.Text = 'Default Comport';
            app.Sierra_Default_Comport_DropDown = uidropdown(app.Sierra_Configuration_Tab);
            app.Sierra_Default_Comport_DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10'};
            app.Sierra_Default_Comport_DropDown.Value = 'COM5';
            app.GasDropDownLabel = uilabel(app.Sierra_Configuration_Tab);
            app.GasDropDownLabel.Text = 'Gas';
            app.Sierra_Gas_DropDown = uidropdown(app.Sierra_Configuration_Tab);
            app.Sierra_Gas_DropDown.Items = {'Air', 'Helium', 'Hydrogen'};
            app.Sierra_Gas_DropDown.ItemsData = {'1', '5', '6'};
            app.Sierra_Gas_DropDown.Value = '1';
            app.UnitsDropDownLabel = uilabel(app.Sierra_Configuration_Tab);
            app.UnitsDropDownLabel.Text = 'Units';
            app.Sierra_Units_DropDown = uidropdown(app.Sierra_Configuration_Tab);
            app.Sierra_Units_DropDown.Items = {'scc/s', '', 'scc/m', '', 'Ncc/s'};
            app.Sierra_Units_DropDown.ItemsData = {'1', '2', '6'};
            app.Sierra_Units_DropDown.Value = '1';
            app.Lakeshore_Configuration_Tab = uitab(app.Configure_Tab_Group);
            app.Lakeshore_Configuration_Tab.Title = 'Lakeshore';
            app.DefaultComportDropDownLabel_2 = uilabel(app.Lakeshore_Configuration_Tab);
            app.DefaultComportDropDownLabel_2.Text = 'Default Comport';
            app.Lakeshore_Default_Comport_DropDown = uidropdown(app.Lakeshore_Configuration_Tab);
            app.Lakeshore_Default_Comport_DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11'};
            app.Lakeshore_Default_Comport_DropDown.Value = 'COM6';
            app.VQM_Configuration_Tab = uitab(app.Configure_Tab_Group);
            app.VQM_Configuration_Tab.Title = 'VQM';
            app.DefaultComportDropDownLabel_3 = uilabel(app.VQM_Configuration_Tab);
            app.DefaultComportDropDownLabel_3.Text = 'Default Comport';
            app.VQM__Default_Comport_DropDown = uidropdown(app.VQM_Configuration_Tab);
            app.VQM__Default_Comport_DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11'};
            app.VQM__Default_Comport_DropDown.Value = 'COM11';
            app.ArduinoTab = uitab(app.Configure_Tab_Group);
            app.ArduinoTab.Title = 'Arduino';
            app.DefaultComportDropDownLabel_4 = uilabel(app.ArduinoTab);
            app.DefaultComportDropDownLabel_4.Text = 'Default Comport';
            app.Arduino_Default_Comport_DropDown = uidropdown(app.ArduinoTab);
            app.Arduino_Default_Comport_DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM13'};
            app.Arduino_Default_Comport_DropDown.Value = 'COM13';
            app.PT_Display_Panel = uipanel(app.LC_Main_Window);
            app.PT_Display_Panel.Title = 'Pressure and Temperature';
            app.PT_Display_Panel.Visible = 'off';
            app.PT_Display_Pressure_Label = uilabel(app.PT_Display_Panel);
            app.PT_Display_Pressure_Label.Text = 'Pressure ';
            app.PT_Display_Pressure_Text = uilabel(app.PT_Display_Panel);
            app.PT_Display_Pressure_Text.Text = '0';
            app.PT_Display_A_Label = uilabel(app.PT_Display_Panel);
            app.PT_Display_A_Label.Text = 'A: ';
            app.PT_Display_C_Label = uilabel(app.PT_Display_Panel);
            app.PT_Display_C_Label.Text = 'C:';
            app.PT_Display_Temperature_A_Text = uilabel(app.PT_Display_Panel);
            app.PT_Display_Temperature_A_Text.Text = '0';
            app.PT_Display_Temperature_C_Text = uilabel(app.PT_Display_Panel);
            app.PT_Display_Temperature_C_Text.Text = '0';
            app.PT_Display_Temperature_Label = uilabel(app.PT_Display_Panel);
            app.PT_Display_Temperature_Label.Text = 'Temperature';
            app.PT_Display_Torr_Label = uilabel(app.PT_Display_Panel);
            app.PT_Display_Torr_Label.Text = 'Torr';
            app.PT_Display_A_Units_Text = uilabel(app.PT_Display_Panel);
            app.PT_Display_A_Units_Text.Text = 'K';
            app.PT_Display_C_Units_Text = uilabel(app.PT_Display_Panel);
            app.PT_Display_C_Units_Text.Text = 'K';
            app.PT_Display_dPdt_Label = uilabel(app.PT_Display_Panel);
            app.PT_Display_dPdt_Label.Text = 'dP/dt';
            app.PT_Display_dPdt_Text = uilabel(app.PT_Display_Panel);
            app.PT_Display_dPdt_Text.Text = '0';
            app.PT_Display_Torr_Label_2 = uilabel(app.PT_Display_Panel);
            app.PT_Display_Torr_Label_2.Text = 'Torr/s';
            app.PlotConfigurationPanel = uipanel(app.LC_Main_Window);
            app.PlotConfigurationPanel.Title = 'Plot Configuration';
            app.PlotConfigurationPanel.Visible = 'off';
            app.XAxisDropDownLabel = uilabel(app.PlotConfigurationPanel);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.PlotConfigurationPanel);
            app.XAxisDropDown.Items = {};
            app.XAxisDropDown.Value = {};
            app.YAxisDropDownLabel = uilabel(app.PlotConfigurationPanel);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.PlotConfigurationPanel);
            app.YAxisDropDown.Items = {};
            app.YAxisDropDown.Value = {};
            app.ApplyButton = uibutton(app.PlotConfigurationPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.PlotConfigurationPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.scaleDropDownLabel = uilabel(app.PlotConfigurationPanel);
            app.scaleDropDownLabel.Text = 'scale';
            app.scaleDropDown = uidropdown(app.PlotConfigurationPanel);
            app.scaleDropDown.Items = {'Linear', 'Log'};
            app.scaleDropDown.Value = 'Linear';
            app.GridCheckBox = uicheckbox(app.PlotConfigurationPanel);
            app.GridCheckBox.Text = 'Grid';
            app.MovingAvgCheckBox = uicheckbox(app.PlotConfigurationPanel);
            app.MovingAvgCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovingAvgCheckBoxValueChanged, true);
            app.MovingAvgCheckBox.Text = 'Moving Avg';
            app.SampleSizeEditFieldLabel = uilabel(app.PlotConfigurationPanel);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.PlotConfigurationPanel, 'numeric');
            app.SampleSizeEditField.Limits = [2 60];
            app.SampleSizeEditField.RoundFractionalValues = 'on';
            app.SampleSizeEditField.Editable = 'off';
            app.SampleSizeEditField.Value = 10;
            % Show the figure after all components are created
            app.LC_Main_Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_Command

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LC_Main_Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LC_Main_Window)
        end
    end
end